classdef FiniteBeamAnalysisGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SetupPanel               matlab.ui.container.Panel
        LeftEndButtonGroup       matlab.ui.container.ButtonGroup
        FixedButton              matlab.ui.control.RadioButton
        SupportedButton          matlab.ui.control.RadioButton
        HangingButton            matlab.ui.control.RadioButton
        RightEndButtonGroup      matlab.ui.container.ButtonGroup
        FixedButton_2            matlab.ui.control.RadioButton
        SupportedButton_2        matlab.ui.control.RadioButton
        HangingButton_2          matlab.ui.control.RadioButton
        Image                    matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        ParametersPanel          matlab.ui.container.Panel
        ElastEEditFieldLabel     matlab.ui.control.Label
        ElastEEditField          matlab.ui.control.EditField
        InertiaIEditFieldLabel   matlab.ui.control.Label
        InertiaIEditField        matlab.ui.control.EditField
        AddElementsPanel         matlab.ui.container.Panel
        AddButton                matlab.ui.control.Button
        TypeofLoadDropDownLabel  matlab.ui.control.Label
        TypeofLoadDropDown       matlab.ui.control.DropDown
        ForceNNmEditFieldLabel   matlab.ui.control.Label
        ForceNNmEditField        matlab.ui.control.NumericEditField
        LengthmEditFieldLabel    matlab.ui.control.Label
        LengthmEditField         matlab.ui.control.NumericEditField
        ResetButton              matlab.ui.control.Button
        RollingSupportCheckBox   matlab.ui.control.CheckBox
        PlotPanel                matlab.ui.container.Panel
        RunButton                matlab.ui.control.Button
        Image3                   matlab.ui.control.Image
        TextArea                 matlab.ui.control.TextArea
        ShearButton              matlab.ui.control.Button
        MomentButton             matlab.ui.control.Button
        DisplacementButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        
    % User chosen parameters
    E % Modulus Elasticity
    I % Moment of Inertia
    W % Force of Load
    L % Length of Load/Beam
    
    % Parameters for solving finite elements
    elements = 0
    matrixSize
    F % Final vector
    
    % Variables for type of load
    isPointLoad
    isUniformLoad
    isTriangularLoadLeftToRight
    isTriangularLoadRightToLeft
    isCoupleMoment
    isBeamLoad
    Type
    roll
    
    % Variables for GUI
    img
    img2
    uifigure = figure('visible', 'off');
    totalLength
    
    % Temporary variables for computation
    tempW
    tempL
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            %% Initialize Data
            app.E=str2double(app.ElastEEditField.Value);
            app.I=str2double(app.InertiaIEditField.Value);
            app.matrixSize=(app.elements * 2) + 2;
            
            %% Element Stifness Matrices
            % Create stiffness matrix for each element
            for i=1:app.elements
                Const = (app.E*app.I)/app.L{i}^3;
                Ktemp{i} =       [ 12   6*app.L{i}  -12   6*app.L{i};
                                 6*app.L{i} 4*app.L{i}^2  -6*app.L{i}  2*app.L{i}^2;
                                 -12   -6*app.L{i}  12  -6*app.L{i};
                                 6*app.L{i}   2*app.L{i}^2  -6*app.L{i}  4*app.L{i}^2];
                % Determine element matrix
                Ktemp{i} = Const * Ktemp{i}; %#ok<*AGROW> 
                % Initialize matrix full of zeroes of full size
                K{i} = zeros(app.matrixSize);
                % Insert into corresponding section (1:4,1:4) (3:6, 3:6) etc
                K{i}((i*2)-1:(i*2)+2,(i*2)-1:(i*2)+2) = Ktemp{i};
            end
            
            %% Element Equivalent Forces
            for i=1:app.elements
                % Determine equivalent forces
                % Check which type of load and computate correspondingly
                if(app.isUniformLoad{i} == 1)
                    Ftemp{i} =  [((-app.W{i}*app.L{i})/2); ((-app.W{i}*app.L{i}^2)/12);((-app.W{i}*app.L{i})/2); ((app.W{i}*app.L{i}^2)/12);];
                end
                
                if(app.isPointLoad{i} == 1)
                    Ftemp{i} =  [-app.W{i}/2; ((-app.W{i}*app.L{i})/8);-app.W{i}/2; ((app.W{i}*app.L{i})/8);];

                end
                
                if(app.isTriangularLoadLeftToRight{i} == 1)
                    Ftemp{i} =  [((-app.W{i}*app.L{i}*3)/20); ((-app.W{i}*app.L{i}^2)/30);((-app.W{i}*app.L{i})*7/20); ((app.W{i}*app.L{i}^2)/20);];
                end
                
                if(app.isTriangularLoadRightToLeft{i} == 1)
                    Ftemp{i} =  [((-app.W{i}*app.L{i})); ((-app.W{i}*app.L{i}^2)/20);((-app.W{i}*app.L{i})*3/20); ((app.W{i}*app.L{i}^2)/30);];
                end
                
                if(app.isCoupleMoment{i} == 1)
                    Ftemp{i} =  [0; ((-app.W{i}*app.L{i}));0; ((app.W{i}*app.L{i}));];
                end
                
                if(app.isBeamLoad{i} == 1)
                    Ftemp{i} =  [-app.W{i}/2; ((-app.W{i}*app.L{i})/8);-app.W{i}/2; ((app.W{i}*app.L{i})/8);];
                end
                % Initialize matrix full of zeroes of full size
                Full{i} = zeros(app.matrixSize, 1);
                % Insert into corresponding section
                Full{i}((i*2)-1:(i*2)+2,1) = Ftemp{i};
            end
            
            %% Assembly
            Kfinal = zeros(app.matrixSize);
            % Loop through elements and add all matrices together
            for i=1:app.elements
              Kfinal = Kfinal + K{i};
            end
            
            % Loop through elements and add all forces together
            Ffinal = zeros(size(Full{1}));
            for i=1:app.elements
                Ffinal = Ffinal + Full{i};
            end
            
            % Boundary conditions
            % Create temporary variables
            KT = Kfinal;
            FET = Ffinal;
            
            % Check which beam types user has chosen and apply
            if(app.FixedButton.Value)
                KT(1,:) = 0;
                KT(:,1) = 0;
                KT(2,:) = 0;
                KT(:,2) = 0;
                KT(1,1) = 1;
                KT(2,2) = 1;
                FET(1) = 0;
                FET(2) = 0;
                annotation('rectangle',[.13 .43 .05 .2], 'Color', 'blue')
            end

            
            if(app.SupportedButton.Value)
                KT(1,:) = 0;
                KT(:,1) = 0;
                KT(1,1) = 1;
                FET(1) = 0;
                annotation('rectangle',[.13 .47 .05 .05], 'Color', 'blue')  
            end
            
            if(app.HangingButton.Value)
                
            end
            
            % Check beam types and apply for end also
            if(app.FixedButton_2.Value)
                KT(app.matrixSize,:) = 0;
                KT(:,app.matrixSize) = 0;
                KT(app.matrixSize-1,:) = 0;
                KT(:,app.matrixSize-1) = 0;
                KT(app.matrixSize,app.matrixSize) = 1;
                KT(app.matrixSize-1,app.matrixSize-1) = 1;
                FET(app.matrixSize) = 0;
                FET(app.matrixSize-1) = 0;
                annotation('rectangle',[.85 .43 .05 .2], 'Color', 'blue')
            end
            
            if(app.SupportedButton_2.Value)
                KT(app.matrixSize-1,:) = 0;
                KT(:,app.matrixSize-1) = 0;
                KT(app.matrixSize-1,app.matrixSize-1) = 1;
                FET(app.matrixSize-1) = 0;
                annotation('rectangle',[.85 .47 .05 .05], 'Color', 'blue')
            end
            
            if(app.HangingButton_2.Value)
                
            end
            
            % If there is more than one element other boundary conditions
            % must be applied.
            if(app.elements>1)
                for i=2:app.elements
                    if(app.roll{i} == true)
                        KT((i*2)-1, :) = 0;
                        KT(:,(i*2)-1) = 0;
                        KT((i*2)-1,(i*2)-1) = 1;
                        FET((i*2)-1) = 0;
                    end
                end
            end
            
            %% Solution
            % F contains the final matrix with shear force and bending
            % moments at each node.
            d = inv(KT)*FET; %#ok<MINV> 
            app.F = Kfinal*d- Ffinal ;
            
            %% Plotting displacements
            % Using the displacement equation and dividing into divisions
            for i=1:app.elements
                div = app.L{i}/10;
                x{i} = 0:div:app.L{i};
                dTemp = [d(i*2-1); d(i*2); d(i*2+1); d(i*2+2)];
                for J=1:11
                    N1 = ((2*x{i}(J)^3)/app.L{i}^3)-((3*x{i}(J)^2)/app.L{i}^2)+1;
                    N2 = ((x{i}(J)^3)/app.L{i}^2)-(2*x{i}(J)^2/app.L{i})+x{i}(J);
                    N3 = (-2*x{i}(J)^3/app.L{i}^3)+(3*x{i}(J)^2/app.L{i}^2);
                    N4 = (x{i}(J)^3/app.L{i}^2)-(x{i}(J)^2/app.L{i});
                    N{i} = [N1 N2 N3 N4];
                    Y{i}(J) = N{i}*dTemp;
                end
            end
            
            Z =0;
            Yfinal =[];
            xfinal = [];
            % Constructing a final matrices for plotting
            % Done by joining all matrices of seperate elements
            for i=1:app.elements
                Yfinal = [Yfinal Y{i}];
                
                if(i>1)
                    Z = (Z + app.L{i-1});
                    xfinal = [xfinal (x{i} + Z)];
                else
                    xfinal = [xfinal (x{i})]; 
                end
            end
                
            ax = gca;
            app.totalLength = 0;
            
            for i=1:app.elements
                app.totalLength = app.totalLength + app.L{i};
            end
            
            % Below contains the code and calculation for adding
            % annotations at correct points.
            Z = app.L{1};
            resize = (max(Yfinal)-min(Yfinal))/0.58;
            if resize==0
                resize = 1;
            end
            for i=1:app.elements
                P{i} = Y{i}(1, 6);
                T{i} = P{i}/resize;
                startPoint =  T{i}+0.55;
                endPoint = T{i}+0.65;
                
                if(startPoint<0)
                    endPoint = 0.1;
                    startPoint = 0.05;
                end
                if(endPoint>1)
                    endPoint = 0.95;
                    startPoint = 0.9;
                end

                if(app.Type{i}~="Beam") 
                    if(i==1)
                        annotation('textarrow',[(((app.L{i}/2)/app.totalLength) * 0.78)+0.12,(((app.L{i}/2)/app.totalLength) * 0.78)+0.12], [endPoint startPoint],'String', sprintf('%s: %d N', app.Type{i}, app.W{i}));
                    else
                        annotation('textarrow',[((((app.L{i}/2)+Z)/app.totalLength) * 0.78)+0.12,((((app.L{i}/2)+Z)/app.totalLength) * 0.78)+0.12], [endPoint startPoint],'String',sprintf('%s: %d N', app.Type{i}, app.W{i}));
                        Z = Z + app.L{i};
                    end
                end
            end
            
            Z = 0;
            for i=2:app.elements
                if(app.roll{i} == true)
                    if(i>1)
                        annotation('ellipse',[(((((app.L{i-1})+Z)/app.totalLength) * 0.78)+0.12) 0.47 .05 .05], 'Color', 'blue');
                        Z = Z + app.L{i-1};
                    end
                else
                    annotation('rectangle',[(((((app.L{i-1})+Z)/app.totalLength) * 0.78)+0.12) 0.47 .001 .05], 'Color', 'green');
                    Z = Z + app.L{i-1};
                end
            end
            
            % Plot displacement and adjust limits
            plot (ax, xfinal,Yfinal,'r','Linewidth',3)
            if(abs(max(Yfinal))>abs(min(Yfinal)))
                ylim([-max(Yfinal) max(Yfinal)]); 
            elseif (abs(max(Yfinal))==abs(min(Yfinal)))
                
            else
                ylim(ax, [min(Yfinal) -min(Yfinal)]);
            end
            xlim([min(xfinal) max(xfinal)]);  
            
            % Figure labelling
            title(ax, 'Beam Deflection');
            xlabel(ax,'Distance (m)')
            ylabel(ax,'Deflection (m)')
            
            % Saving figure and refreshing
            saveas(app.uifigure, 'displacement', 'bmp');
            app.img = imread("displacement.bmp");
            app.Image3.ImageSource = app.img;
            
            % Updating result box on right hand side
            app.TextArea.Value = "";
            for i=1:app.elements+1
                app.TextArea.Value = [string(app.TextArea.Value); sprintf("Node: %d " ,i)]; 
                app.TextArea.Value = [string(app.TextArea.Value); sprintf("F: %.5g " ,(app.F(i*2-1)))]; 
                app.TextArea.Value = [string(app.TextArea.Value); sprintf("M: %.5g" ,(app.F(i*2)))]; 
                app.TextArea.Value = [string(app.TextArea.Value); newline]; 
            end

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Parse all the variables set by user into arrays
            app.elements = app.elements + 1;
            app.W{app.elements}=app.ForceNNmEditField.Value;
            app.L{app.elements}=app.LengthmEditField.Value;
            app.Type{app.elements} = app.TypeofLoadDropDown.Value;
            
            % Check type of load
            if(app.RollingSupportCheckBox.Value == 1)
                app.roll{app.elements} = 1;
            else
                app.roll{app.elements} = 0;
            end
            if(app.TypeofLoadDropDown.Value == "Point")
                app.isPointLoad{app.elements} = 1; 
            else
                app.isPointLoad{app.elements} = 0; 
            end
            if(app.TypeofLoadDropDown.Value == "Beam")
                app.isBeamLoad{app.elements} = 1; 
            else
                app.isBeamLoad{app.elements} = 0; 
            end
                        
            if(app.TypeofLoadDropDown.Value == "Uniform")
                app.isUniformLoad{app.elements} = 1; 
            else
                app.isUniformLoad{app.elements} = 0; 
            end
            if(app.TypeofLoadDropDown.Value == "Triangular LTR")
                app.isTriangularLoadLeftToRight{app.elements} = 1; 
            else
                app.isTriangularLoadLeftToRight{app.elements} = 0; 
            end
            if(app.TypeofLoadDropDown.Value == "Triangular RTL")
                app.isTriangularLoadRightToLeft{app.elements} = 1; 
            else
                app.isTriangularLoadRightToLeft{app.elements} = 0; 
            end
            if(app.TypeofLoadDropDown.Value == "Couple Moment")
                app.isCoupleMoment{app.elements} = 1; 
            else
                app.isCoupleMoment{app.elements} = 0; 
            end
            delete(findall(gcf,'type','annotation'))
            % Runs the main program to refresh the plot
            RunButtonPushed(app,event);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %% Reset clears all variables and restarts the app
            app.elements = 0;
            app.img = imread("default.bmp");
            app.Image3.ImageSource = app.img;
            clear();
        end

        % Selection changed function: LeftEndButtonGroup
        function LeftEndButtonGroupSelectionChanged(app, event)
            %% Changing beam end types refreshes plot
            delete(findall(gcf,'type','annotation'))
            RunButtonPushed(app,event);
        end

        % Selection changed function: RightEndButtonGroup
        function RightEndButtonGroupSelectionChanged(app, event)

            delete(findall(gcf,'type','annotation'))
            RunButtonPushed(app,event);
        end

        % Button pushed function: ShearButton
        function ShearButtonPushed(app, event)
            %% Replaces displacement graph with shear graph
            delete(findall(gcf,'type','annotation'))
            Ltemp = (0);
            Z = 0;
            for i=1:app.elements
                Ltemp = [Ltemp, app.L{i}+Z];
                Z = Z + app.L{i};
            end

            for i=1:app.elements + 1
                shear{i} = app.F(i*2-1);
            end
                        
            shearfinal =[];
            
            for i=1:app.elements +1
                shearfinal = [shearfinal shear{i}];
            end
            
            plot(Ltemp, shearfinal);
            title('Shear Force Diagram');
            xlabel('Distance (m)')
            ylabel('Shear Force (N)')

            saveas(app.uifigure, 'shear', 'bmp');
            app.img = imread("shear.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: MomentButton
        function MomentButtonPushed(app, event)
            %% Replaces displacement graph with moment graph
            delete(findall(gcf,'type','annotation'))
            Ltemp = (0);
            Z = 0;
            for i=1:app.elements
                Ltemp = [Ltemp, app.L{i}+Z];
                Z = Z + app.L{i};
            end

            for i=1:app.elements + 1
                moment{i} = app.F(i*2);
            end
                       
            momentfinal = [];
            
            for i=1:app.elements +1
                momentfinal = [momentfinal moment{i}];
            end
            
            plot(Ltemp, momentfinal);
            title('Bending Moment Diagram');
            xlabel('Distance (m)')
            ylabel('Bendiming Moment (N-m)')
            saveas(app.uifigure, 'moment', 'bmp');
            app.img = imread("moment.bmp");
            app.Image3.ImageSource = app.img;
        end

        % Button pushed function: DisplacementButton
        function DisplacementButtonPushed(app, event)
            app.img = imread("displacement.bmp");
            app.Image3.ImageSource = app.img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SetupPanel = uipanel(app.UIFigure);
            app.SetupPanel.Title = 'Setup';
            app.LeftEndButtonGroup = uibuttongroup(app.SetupPanel);
            app.LeftEndButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LeftEndButtonGroupSelectionChanged, true);
            app.LeftEndButtonGroup.Title = 'Left End';
            app.FixedButton = uiradiobutton(app.LeftEndButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.SupportedButton = uiradiobutton(app.LeftEndButtonGroup);
            app.SupportedButton.Text = 'Supported';
            app.HangingButton = uiradiobutton(app.LeftEndButtonGroup);
            app.HangingButton.Text = 'Hanging';
            app.RightEndButtonGroup = uibuttongroup(app.SetupPanel);
            app.RightEndButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RightEndButtonGroupSelectionChanged, true);
            app.RightEndButtonGroup.Title = 'Right End';
            app.FixedButton_2 = uiradiobutton(app.RightEndButtonGroup);
            app.FixedButton_2.Text = 'Fixed';
            app.FixedButton_2.Value = true;
            app.SupportedButton_2 = uiradiobutton(app.RightEndButtonGroup);
            app.SupportedButton_2.Text = 'Supported';
            app.HangingButton_2 = uiradiobutton(app.RightEndButtonGroup);
            app.HangingButton_2.Text = 'Hanging';
            app.Image = uiimage(app.SetupPanel);
            app.Image.ImageSource = 'helpimage.png';
            app.Image2 = uiimage(app.SetupPanel);
            app.Image2.ImageSource = 'helpimage2.png';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ElastEEditFieldLabel = uilabel(app.ParametersPanel);
            app.ElastEEditFieldLabel.Text = 'Elast. (E)';
            app.ElastEEditField = uieditfield(app.ParametersPanel, 'text');
            app.ElastEEditField.Value = '200E9';
            app.InertiaIEditFieldLabel = uilabel(app.ParametersPanel);
            app.InertiaIEditFieldLabel.Text = {'Inertia (I)'; ''};
            app.InertiaIEditField = uieditfield(app.ParametersPanel, 'text');
            app.InertiaIEditField.Value = '78.5E-6';
            app.AddElementsPanel = uipanel(app.UIFigure);
            app.AddElementsPanel.Title = 'Add Elements';
            app.AddButton = uibutton(app.AddElementsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TypeofLoadDropDownLabel = uilabel(app.AddElementsPanel);
            app.TypeofLoadDropDownLabel.Text = {'Type of Load'; ''};
            app.TypeofLoadDropDown = uidropdown(app.AddElementsPanel);
            app.TypeofLoadDropDown.Items = {'Beam', 'Point', 'Uniform', 'Triangular LTR', 'Triangular RTL', 'Couple Moment'};
            app.TypeofLoadDropDown.Value = 'Uniform';
            app.ForceNNmEditFieldLabel = uilabel(app.AddElementsPanel);
            app.ForceNNmEditFieldLabel.Text = 'Force (N/Nm)';
            app.ForceNNmEditField = uieditfield(app.AddElementsPanel, 'numeric');
            app.ForceNNmEditField.Value = 30000;
            app.LengthmEditFieldLabel = uilabel(app.AddElementsPanel);
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.LengthmEditField = uieditfield(app.AddElementsPanel, 'numeric');
            app.LengthmEditField.Value = 3;
            app.ResetButton = uibutton(app.AddElementsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RollingSupportCheckBox = uicheckbox(app.AddElementsPanel);
            app.RollingSupportCheckBox.Text = 'Rolling Support';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Plot';
            app.RunButton = uibutton(app.PlotPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Visible = 'off';
            app.RunButton.Text = 'Run';
            app.Image3 = uiimage(app.PlotPanel);
            app.Image3.ImageSource = 'default.jpg';
            app.TextArea = uitextarea(app.PlotPanel);
            app.ShearButton = uibutton(app.PlotPanel, 'push');
            app.ShearButton.ButtonPushedFcn = createCallbackFcn(app, @ShearButtonPushed, true);
            app.ShearButton.Text = 'Shear';
            app.MomentButton = uibutton(app.PlotPanel, 'push');
            app.MomentButton.ButtonPushedFcn = createCallbackFcn(app, @MomentButtonPushed, true);
            app.MomentButton.Text = {'Moment'; ''};
            app.DisplacementButton = uibutton(app.PlotPanel, 'push');
            app.DisplacementButton.ButtonPushedFcn = createCallbackFcn(app, @DisplacementButtonPushed, true);
            app.DisplacementButton.Text = 'Displacement';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiniteBeamAnalysisGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end